classdef waveFront_Algorithm_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearMapButton              matlab.ui.control.Button
        MessagesLabel               matlab.ui.control.Label
        MapMatrixLabel              matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        GoalEditField               matlab.ui.control.EditField
        GoalEditFieldLabel          matlab.ui.control.Label
        StartEditField              matlab.ui.control.EditField
        StartEditFieldLabel         matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        mapSizeEditField            matlab.ui.control.EditField
        mapSizeEditFieldLabel       matlab.ui.control.Label
        WavefrontPathPlanningLabel  matlab.ui.control.Label
        ConnectednessSwitch         matlab.ui.control.Switch
        ConnectednessSwitchLabel    matlab.ui.control.Label
        ExportButton                matlab.ui.control.Button
        GeneratePathButton          matlab.ui.control.Button
        WavefrontButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CostMap % store the map after the Wavefront runs
        Path
        PathLength
        foundStart
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.mapSizeEditField.Value;
            sizeVector = str2num(value);

            app.UITable.Data = zeros(sizeVector(1), sizeVector(2));
            app.UITable.ColumnWidth = 'fit';

            mapData = app.UITable.Data;
            imagesc(app.UIAxes, mapData);
            axis(app.UIAxes, 'square');
            colormap(app.UIAxes, [0.5 0.5 0.5; 0 0 0; 0 0 1]);
            axis(app.UIAxes, 'off');
            app.UIAxes.CLim = [0 2];
            app.UIAxes.PositionConstraint = 'innerposition';
            
            [rows, cols] = size(mapData);

            app.UITable.ColumnName = 1:cols; % Set as numbers
            app.UITable.RowName = 1:rows;    % Set as numbers
            app.UITable.ColumnWidth = repmat({'1x'}, 1, cols);

            txt_handles = gobjects(rows, cols); % Pre-allocate 
            for r = 1:rows
                for c = 1:cols
                    % Initialize with the current MAP value (0 or 1)
                    txt_handles(r,c) = text(app.UIAxes, c, r, num2str(mapData(r,c)), ...
                        'HorizontalAlignment', 'center', 'Color', 'white');
                end
            end
            app.MessagesLabel.Text = 'Specify map Size, select connetedness, start and goal point';
        end

        % Value changed function: mapSizeEditField
        function mapSizeEditFieldValueChanged(app, event)
            value = app.mapSizeEditField.Value;
            sizeVector = str2num(value);

            mapData = zeros(sizeVector(1), sizeVector(2));
            app.UITable.Data = mapData;
            app.UITable.ColumnWidth = 'fit';
            
            [rows, cols] = size(mapData);

            app.UITable.ColumnName = 1:cols; % Set as numbers
            app.UITable.RowName = 1:rows;    % Set as numbers
            app.UITable.ColumnWidth = repmat({'1x'}, 1, cols);

            imagesc(app.UIAxes, mapData);
            axis(app.UIAxes, 'square');
            colormap(app.UIAxes, [0.5 0.5 0.5; 0 0 0; 0 0 1]);
            app.UIAxes.CLim = [0 2];
            axis(app.UIAxes, 'off');
            app.UIAxes.PositionConstraint = 'innerposition';

            [rows, cols] = size(mapData);

            txt_handles = gobjects(rows, cols); % Pre-allocate 
            for r = 1:rows
                for c = 1:cols
                    % Initialize with the current MAP value (0 or 1)
                    txt_handles(r,c) = text(app.UIAxes, c, r, num2str(mapData(r,c)), ...
                        'HorizontalAlignment', 'center', 'Color', 'white');
                end
            end
            
        end

        % Callback function
        function DoneButtonPushed(app, event)
            
        end

        % Button pushed function: WavefrontButton
        function WavefrontButtonPushed(app, event)
            map = app.UITable.Data;
            [rows, cols] = size(map);

            startField = app.StartEditField.Value;
            start = str2num(startField);
            goalField = app.GoalEditField.Value;
            goal = str2num(goalField);

            if any(start < 1) || start(1) > rows || start(2) > cols || ...
                    any(goal < 1)  || goal(1) > rows  || goal(2) > cols
                app.MessagesLabel.Text = 'Error: Start or Goal is outside map boundaries!';
                return; % Exit function
            end
            
            if map(start(1), start(2)) == 1 || map(goal(1), goal(2)) == 1
                app.MessagesLabel.Text = 'Error: Start or Goal is placed on an obstacle!';
                return; % Exit function
            end

            if isequal(start, goal)
                app.MessagesLabel.Text = 'Error: Start and Goal cannot be the same point!';
                return; % Exit function
            end

            app.MessagesLabel.Text = 'Running Wavefront...';

            hImg = imagesc(app.UIAxes, map);
            axis(app.UIAxes, 'off');
            app.UIAxes.PositionConstraint = 'innerposition';
            txt_handles = gobjects(rows, cols); % Pre-allocate 
            for r = 1:rows
                for c = 1:cols
                    % Initialize with the current MAP value (0 or 1)
                    txt_handles(r,c) = text(app.UIAxes, c, r, num2str(map(r,c)), ...
                        'HorizontalAlignment', 'center', 'Color', 'white');
                end
            end
            

            % Verify that Start and Goal are not on obstacles
            if map(start(1), start(2)) == 1 || map(goal(1), goal(2)) == 1
                 app.MessagesLabel.Text = 'Invalid Input: Start or Goal is placed on an obstacle!';
            end
            
            currentValue = 2;
            map(goal(1), goal(2)) = currentValue;
            txt_handles(goal(1), goal(2)).String = num2str(currentValue);
            queue = goal;
            app.foundStart = false;

            connectedness = str2double(app.ConnectednessSwitch.Value);
            % Pre-define neighbor offsets for speed
            if connectedness == 4
                offsets = [-1 0; 1 0; 0 -1; 0 1]; 
            else
                offsets = [-1 0; 1 0; 0 -1; 0 1; -1 -1; -1 1; 1 -1; 1 1];
            end

            % --- Phase 1: Wavefront Propagation ---
            while ~isempty(queue) && ~app.foundStart
                currentCell = queue(1,:);
                % dequeue
                currentValue = map(currentCell(1), currentCell(2));
                queue(1,:) = [];
            
                allNeighbors = currentCell + offsets;

                for j = 1:size(allNeighbors, 1)
                    nr = allNeighbors(j, 1);
                    nc = allNeighbors(j, 2);
            
                    if nr>=1 && nr<= rows && nc>=1 && nc<=cols
                        if map(nr, nc) == 0
                            map(nr, nc) = currentValue + 1;
                            txt_handles(nr, nc).String = num2str(map(nr, nc));
                            % Enqueue
                            queue = [queue; nr, nc];
                            if nr == start(1) && nc == start(2)
                                app.foundStart = true;
                                app.MessagesLabel.Text = 'Path Available';
                                break;
                            end
                        end
                    end
                end
                hImg.CData = map;
                app.CostMap = map;
                drawnow;
                
                %pause(0.1)
            end
            % Check if queue emptied without finding start
            if ~app.foundStart
                app.MessagesLabel.Text = 'Wavefront Complete: No Path Possible.';
            end
        end

        % Button pushed function: GeneratePathButton
        function GeneratePathButtonPushed(app, event)
            % Check if the Wavefront has even been attempted (is CostMap empty?)
            if isempty(app.CostMap)
                app.MessagesLabel.Text = 'Error: Please run the "Wavefront" algorithm first!';
                return;
            end

            if app.foundStart
                
                map = app.CostMap;
                [rows, cols] = size(map);
                startField = app.StartEditField.Value;
                start = str2num(startField);
                currentCell = start;
                path = currentCell;
                
                connectedness = str2double(app.ConnectednessSwitch.Value);
                
                % Pre-define neighbor offsets for speed
                if connectedness == 4
                    offsets = [-1 0; 1 0; 0 -1; 0 1]; 
                else
                    offsets = [-1 0; 1 0; 0 -1; 0 1; -1 -1; -1 1; 1 -1; 1 1];
                end
                
                while map(currentCell(1), currentCell(2)) > 2
                     allNeighbors = currentCell + offsets;
    
                    for j = 1:size(allNeighbors, 1)
                        nr = allNeighbors(j, 1);
                        nc = allNeighbors(j, 2);
                        
                        if nr>=1 && nr<= rows && nc>=1 && nc<=cols
                            if map(nr, nc) == map(currentCell(1), currentCell(2)) - 1
                                currentCell = [nr nc];
                                path = [path; currentCell];
                                break;
                            end
                        end
                    end
                end
    
                % Save results to app properties
                app.Path = path;
                app.PathLength = size(path, 1) - 1;
                hold(app.UIAxes, 'on');
                
                plot(app.UIAxes, path(:,2), path(:,1), 'r-', 'LineWidth', 3); 
    
                % Add a marker for Start and Goal
                plot(app.UIAxes, path(1,2), path(1,1), 'go', 'MarkerSize', 15, 'MarkerFaceColor','g'); % Start
                plot(app.UIAxes, path(end,2), path(end,1), 'ro', 'MarkerSize', 15, 'MarkerFaceColor', 'r'); % Goal
                % Find all text objects in the axes and pull them to the front
                allText = findobj(app.UIAxes, 'Type', 'text');
                uistack(allText, 'top');
                hold(app.UIAxes, 'off');
                app.MessagesLabel.Text = {['Path Found: ', num2str(app.PathLength), ' steps']};
            else
                app.MessagesLabel.Text = 'Path NOT Found';
            end

        end

        % Value changed function: ConnectednessSwitch
        function ConnectednessSwitchValueChanged(app, event)
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.CostMap) || isempty(app.Path)
                app.MessagesLabel.Text = 'Please run Wavefront and Generate Path first!';
                return;
            end

            % Assign variables to the Base Workspace
            assignin('base', 'wavefront_MAP', app.CostMap);
            assignin('base', 'path_indexes', app.Path);
            assignin('base', 'path_cost', app.PathLength);

            % Display in Command Window
            disp('Wavefront MAP:'); disp(app.CostMap);
            disp('Path Indexes:'); disp(app.Path);
            fprintf('Cost of the path (length): %d steps\n', app.PathLength);
            app.MessagesLabel.Text = 'Data Exported Successfully';
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            mapData = app.UITable.Data;
            imagesc(app.UIAxes, mapData);
            axis(app.UIAxes, 'square');
            colormap(app.UIAxes, [0.5 0.5 0.5; 0 0 0; 0 0 1]);
            app.UIAxes.CLim = [0 2];
            app.UIAxes.XTick = []; 
            app.UIAxes.YTick = [];

            [rows, cols] = size(mapData);

            txt_handles = gobjects(rows, cols); % Pre-allocate 
            for r = 1:rows
                for c = 1:cols
                    % Initialize with the current MAP value (0 or 1)
                    txt_handles(r,c) = text(app.UIAxes, c, r, num2str(mapData(r,c)), ...
                        'HorizontalAlignment', 'center', 'Color', 'white');
                end
            end
            
        end

        % Button pushed function: ClearMapButton
        function ClearMapButtonPushed(app, event)
            app.CostMap = [];
            app.Path = [];
            app.foundStart = false;
            
            value = app.mapSizeEditField.Value;
            sizeVector = str2num(value);
            app.UITable.Data = zeros(sizeVector(1), sizeVector(2));

            mapData = app.UITable.Data;
            imagesc(app.UIAxes, mapData);

            axis(app.UIAxes, 'square');
            colormap(app.UIAxes, [0.5 0.5 0.5; 0 0 0; 0 0 1]);
            app.UIAxes.CLim = [0 2];
            app.UIAxes.XTick = []; 
            app.UIAxes.YTick = [];

            [rows, cols] = size(mapData);

            txt_handles = gobjects(rows, cols); % Pre-allocate 
            for r = 1:rows
                for c = 1:cols
                    % Initialize with the current MAP value (0 or 1)
                    txt_handles(r,c) = text(app.UIAxes, c, r, num2str(mapData(r,c)), ...
                        'HorizontalAlignment', 'center', 'Color', 'white');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'Wavefront Path Planner App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MAP')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.WavefrontButton = uibutton(app.UIFigure, 'push');
            app.WavefrontButton.ButtonPushedFcn = createCallbackFcn(app, @WavefrontButtonPushed, true);
            app.WavefrontButton.Text = 'Wavefront';
            app.GeneratePathButton = uibutton(app.UIFigure, 'push');
            app.GeneratePathButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePathButtonPushed, true);
            app.GeneratePathButton.Text = 'Generate Path';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ConnectednessSwitchLabel = uilabel(app.UIFigure);
            app.ConnectednessSwitchLabel.Text = 'Connectedness';
            app.ConnectednessSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectednessSwitch.Items = {'8', '4'};
            app.ConnectednessSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectednessSwitchValueChanged, true);
            app.ConnectednessSwitch.Value = '4';
            app.WavefrontPathPlanningLabel = uilabel(app.UIFigure);
            app.WavefrontPathPlanningLabel.Text = 'Wavefront Path Planning';
            app.mapSizeEditFieldLabel = uilabel(app.UIFigure);
            app.mapSizeEditFieldLabel.Text = 'mapSize';
            app.mapSizeEditField = uieditfield(app.UIFigure, 'text');
            app.mapSizeEditField.ValueChangedFcn = createCallbackFcn(app, @mapSizeEditFieldValueChanged, true);
            app.mapSizeEditField.Value = '[6 6]';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'text');
            app.StartEditField.Value = '[1 1]';
            app.GoalEditFieldLabel = uilabel(app.UIFigure);
            app.GoalEditFieldLabel.Text = 'Goal';
            app.GoalEditField = uieditfield(app.UIFigure, 'text');
            app.GoalEditField.Value = '[4 4]';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Note: Assign cells to 1 to specify objects. ';
            app.MapMatrixLabel = uilabel(app.UIFigure);
            app.MapMatrixLabel.Text = 'Map Matrix';
            app.MessagesLabel = uilabel(app.UIFigure);
            app.MessagesLabel.Text = 'Messages';
            app.ClearMapButton = uibutton(app.UIFigure, 'push');
            app.ClearMapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMapButtonPushed, true);
            app.ClearMapButton.Text = 'Clear Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveFront_Algorithm_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end